classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        TabGroup                  matlab.ui.container.TabGroup
        ConvolutionTab            matlab.ui.container.Tab
        UploadImageButton         matlab.ui.control.Button
        conv_res_img              matlab.ui.control.Image
        conv_base_img             matlab.ui.control.Image
        FiltersTab                matlab.ui.container.Tab
        cutofffreqEditField       matlab.ui.control.NumericEditField
        cutofffreqEditFieldLabel  matlab.ui.control.Label
        sigmaEditField            matlab.ui.control.NumericEditField
        sigmaEditFieldLabel       matlab.ui.control.Label
        ButtonGroup               matlab.ui.container.ButtonGroup
        BHPFButton                matlab.ui.control.RadioButton
        GHPFButton                matlab.ui.control.RadioButton
        IHPFButton                matlab.ui.control.RadioButton
        BLPFButton                matlab.ui.control.RadioButton
        GLPFButton                matlab.ui.control.RadioButton
        ILPFButton                matlab.ui.control.RadioButton
        GaussButton               matlab.ui.control.RadioButton
        MeanButton                matlab.ui.control.RadioButton
        UploadFilterImageButton   matlab.ui.control.Button
        filter_res_img            matlab.ui.control.Image
        filter_base_img           matlab.ui.control.Image
        FrequencyFilteringTab     matlab.ui.container.Tab
        factor                    matlab.ui.control.NumericEditField
        factorLabel               matlab.ui.control.Label
        UploadFrequencyFilterImageButton  matlab.ui.control.Button
        initial_img               matlab.ui.control.Image
        filtered_img              matlab.ui.control.Image
    end

    
    properties (Access = private)
        imageFilterMethod uint8 = 0
        inputImageForFilter = 0
    end
    
    methods (Access = private)
        
        function updateImageFilter(app)
            if (app.inputImageForFilter == 0)
                return
            end
            switch(app.imageFilterMethod)
                case 1
                    res=filter.mean(app.inputImageForFilter);
                case 2
                    if (app.sigmaEditField.Value<=0)
                        app.sigmaEditField.Value=1;
                    end
                    res=filter.gauss(app.inputImageForFilter,app.sigmaEditField.Value);
                case 3
                    res=filter.ilpf(app.inputImageForFilter,app.sigmaEditField.Value);
                case 4
                    res=filter.glpf(app.inputImageForFilter,app.sigmaEditField.Value);
                case 5
                    res=filter.blpf(app.inputImageForFilter,app.sigmaEditField.Value,app.cutofffreqEditField.Value);
                case 6
                    res=filter.ihpf(app.inputImageForFilter,app.sigmaEditField.Value);
                case 7
                    res=filter.ghpf(app.inputImageForFilter,app.sigmaEditField.Value);
                case 8
                    res=filter.bhpf(app.inputImageForFilter,app.sigmaEditField.Value,app.cutofffreqEditField.Value);
            end

            if size(res, 3) < 3
                res = repmat(res, [1, 1, 3]);
            end

            app.filter_res_img.ImageSource=res;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: UploadImageButton
        function UploadImageButtonPushed(app, event)
            [file, path] = uigetfile({'*.jpg;*.jpeg;*.png;*.bmp', 'Image Files (*.jpg, *.jpeg, *.png, *.bmp)'});
            if isequal(file, 0)
                return;
            end
            img = imread(fullfile(path, file));
            % imshow(img,'Parent',app.conv_base_img);
            if size(img, 3) < 3
                img = repmat(img, [1, 1, 3]);
            end
            app.conv_base_img.ImageSource=uint8(img);
            % g=[1 2 1; 2 4 2; 1 2 1]*(1/16);
            % g=[-1 -1 -1; -1 8 -1; -1 -1 -1];
            % g = [0 -1 0; -1 4 -1; 0 -1 0];
            % g = fspecial("gaussian",[7,7],7);
            g = (1/140)*[1 1 2 2 2 1 1;
                1 2 2 4 2 2 1;
                2 2 4 8 4 2 2;
                2 4 8 16 8 4 2;
                2 2 4 8 4 2 2;
                1 2 2 4 2 2 1;
                1 1 2 2 2 1 1;
                ];
            % g = [-1 -1 -1; -1 17 -1; -1 -1 -1];
            res = convolution(im2double(img),double(g));
            if size(res, 3) < 3
                res = repmat(res, [1, 1, 3]);
            end
            app.conv_res_img.ImageSource=res;
        end

        % Button pushed function: UploadFilterImageButton
        function UploadFilterImageButtonPushed(app, event)
            [file, path] = uigetfile({'*.jpg;*.jpeg;*.png;*.bmp', 'Image Files (*.jpg, *.jpeg, *.png, *.bmp)'});
            if isequal(file, 0)
                return;
            end
            img = imread(fullfile(path, file));
            if size(img, 3) < 3
                img = repmat(img, [1, 1, 3]);
            end
            app.inputImageForFilter=img;
            app.filter_base_img.ImageSource=img;
            if (app.imageFilterMethod == 0)
                app.imageFilterMethod = 1;
            end

            updateImageFilter(app);
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;
            param1=[app.sigmaEditFieldLabel,app.sigmaEditField]
            param2=[app.cutofffreqEditFieldLabel,app.cutofffreqEditField]
            if(strcmp(selectedButton.Text,"Mean"))
                app.imageFilterMethod=1;
                set(param1,"visible","off");
                set(param2,"visible","off");
            elseif(strcmp(selectedButton.Text,"Gauss"))
                app.imageFilterMethod=2;
                app.sigmaEditFieldLabel.Text="sigma";
                set(param1,"visible","on");
                set(param2,"visible","off");
            elseif(strcmp(selectedButton.Text,"ILPF"))
                app.imageFilterMethod=3;
                app.sigmaEditFieldLabel.Text="cutoff freq";
                set(param1,"visible","on");
                set(param2,"visible","off");
            elseif(strcmp(selectedButton.Text,"GLPF"))
                app.imageFilterMethod=4;
                app.sigmaEditFieldLabel.Text="cutoff freq";
                set(param1,"visible","on");
                set(param2,"visible","off");
            elseif(strcmp(selectedButton.Text,"BLPF"))
                app.imageFilterMethod=5;
                app.sigmaEditFieldLabel.Text="cutoff freq";
                set(param1,"visible","on");
                app.cutofffreqEditFieldLabel.Text="n";
                set(param2,"visible","on");
            elseif(strcmp(selectedButton.Text,"IHPF"))
                app.imageFilterMethod=6;
                app.sigmaEditFieldLabel.Text="cutoff freq";
                set(param1,"visible","on");
                set(param2,"visible","off");
            elseif(strcmp(selectedButton.Text,"GHPF"))
                app.imageFilterMethod=7;
                app.sigmaEditFieldLabel.Text="cutoff freq";
                set(param1,"visible","on");
                set(param2,"visible","off");
            else
                app.imageFilterMethod=8;
                app.sigmaEditFieldLabel.Text="cutoff freq";
                set(param1,"visible","on");
                app.cutofffreqEditFieldLabel.Text="n";
                set(param2,"visible","on");
            end

            updateImageFilter(app);
        end

        % Value changed function: sigmaEditField
        function sigmaEditFieldValueChanged(app, event)
            value = app.sigmaEditField.Value;
            updateImageFilter(app);
        end

        % Value changed function: cutofffreqEditField
        function cutofffreqEditFieldValueChanged(app, event)
            value = app.cutofffreqEditField.Value;
            updateImageFilter(app);
        end

        % Button pushed function: UploadFrequencyFilterImageButton
        function UploadFrequencyFilterImageButtonPushed(app, event)
            value = app.factor.Value;
            [file, path] = uigetfile({'*.jpg;*.jpeg;*.png;*.bmp', 'Image Files (*.jpg, *.jpeg, *.png, *.bmp)'});
            if isequal(file, 0)
                return;
            end

            img = imread(fullfile(path, file));
            if size(img, 3) < 3
                img = repmat(img, [1, 1, 3]);
            end

            app.initial_img.ImageSource = img;
            result_img = freqFiltering(img, value);
            if (size(result_img, 3) < 3)
                result_img = repmat(result_img, [1, 1, 3]);
            end
            app.filtered_img.ImageSource = result_img;
        end

        % Value changed function: factor
        function factorValueChanged(app, event)
            if isempty(app.initial_img.ImageSource)
                return;
            end

            value = app.factor.Value;
            app.filtered_img.ImageSource = freqFiltering(app.initial_img.ImageSource, value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ConvolutionTab = uitab(app.TabGroup);
            app.ConvolutionTab.Title = 'Convolution';
            app.conv_base_img = uiimage(app.ConvolutionTab);
            app.conv_base_img.Tag = 'conv_base';
            app.conv_res_img = uiimage(app.ConvolutionTab);
            app.conv_res_img.Tag = 'conv_base';
            app.UploadImageButton = uibutton(app.ConvolutionTab, 'push');
            app.UploadImageButton.ButtonPushedFcn = createCallbackFcn(app, @UploadImageButtonPushed, true);
            app.UploadImageButton.Text = 'Upload Image';
            app.FiltersTab = uitab(app.TabGroup);
            app.FiltersTab.Title = 'Filters';
            app.filter_base_img = uiimage(app.FiltersTab);
            app.filter_base_img.Tag = 'conv_base';
            app.filter_res_img = uiimage(app.FiltersTab);
            app.filter_res_img.Tag = 'conv_base';
            app.UploadFilterImageButton = uibutton(app.FiltersTab, 'push');
            app.UploadFilterImageButton.ButtonPushedFcn = createCallbackFcn(app, @UploadFilterImageButtonPushed, true);
            app.UploadFilterImageButton.Text = 'Upload Image';
            app.ButtonGroup = uibuttongroup(app.FiltersTab);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.ButtonGroup.Title = 'Button Group';
            app.MeanButton = uiradiobutton(app.ButtonGroup);
            app.MeanButton.Text = 'Mean';
            app.MeanButton.Value = true;
            app.GaussButton = uiradiobutton(app.ButtonGroup);
            app.GaussButton.Text = 'Gauss';
            app.ILPFButton = uiradiobutton(app.ButtonGroup);
            app.ILPFButton.Text = 'ILPF';
            app.GLPFButton = uiradiobutton(app.ButtonGroup);
            app.GLPFButton.Text = 'GLPF';
            app.BLPFButton = uiradiobutton(app.ButtonGroup);
            app.BLPFButton.Text = 'BLPF';
            app.IHPFButton = uiradiobutton(app.ButtonGroup);
            app.IHPFButton.Text = 'IHPF';
            app.GHPFButton = uiradiobutton(app.ButtonGroup);
            app.GHPFButton.Text = 'GHPF';
            app.BHPFButton = uiradiobutton(app.ButtonGroup);
            app.BHPFButton.Text = 'BHPF';
            app.sigmaEditFieldLabel = uilabel(app.FiltersTab);
            app.sigmaEditFieldLabel.Visible = 'off';
            app.sigmaEditFieldLabel.Text = 'sigma';
            app.sigmaEditField = uieditfield(app.FiltersTab, 'numeric');
            app.sigmaEditField.ValueChangedFcn = createCallbackFcn(app, @sigmaEditFieldValueChanged, true);
            app.sigmaEditField.Visible = 'off';
            app.cutofffreqEditFieldLabel = uilabel(app.FiltersTab);
            app.cutofffreqEditFieldLabel.Visible = 'off';
            app.cutofffreqEditFieldLabel.Text = 'cutoff freq';
            app.cutofffreqEditField = uieditfield(app.FiltersTab, 'numeric');
            app.cutofffreqEditField.ValueChangedFcn = createCallbackFcn(app, @cutofffreqEditFieldValueChanged, true);
            app.cutofffreqEditField.Visible = 'off';
            app.FrequencyFilteringTab = uitab(app.TabGroup);
            app.FrequencyFilteringTab.Title = 'Frequency Filtering';
            app.filtered_img = uiimage(app.FrequencyFilteringTab);
            app.initial_img = uiimage(app.FrequencyFilteringTab);
            app.UploadFrequencyFilterImageButton = uibutton(app.FrequencyFilteringTab, 'push');
            app.UploadFrequencyFilterImageButton.ButtonPushedFcn = createCallbackFcn(app, @UploadFrequencyFilterImageButtonPushed, true);
            app.UploadFrequencyFilterImageButton.Text = 'Upload Image';
            app.factorLabel = uilabel(app.FrequencyFilteringTab);
            app.factorLabel.Text = 'factor';
            app.factor = uieditfield(app.FrequencyFilteringTab, 'numeric');
            app.factor.ValueChangedFcn = createCallbackFcn(app, @factorValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end